classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Button_Plot             matlab.ui.control.Button
        EditField_Color         matlab.ui.control.EditField
        ColorEditFieldLabel     matlab.ui.control.Label
        EditField_FontSize      matlab.ui.control.EditField
        FontSizeEditFieldLabel  matlab.ui.control.Label
        EditField_YLabel        matlab.ui.control.EditField
        YLabelEditFieldLabel    matlab.ui.control.Label
        EditField_XLabel        matlab.ui.control.EditField
        XLabelEditFieldLabel    matlab.ui.control.Label
        EditField_Title         matlab.ui.control.EditField
        TitleEditFieldLabel     matlab.ui.control.Label
        DropDown_XVar           matlab.ui.control.DropDown
        VariableXDropDownLabel  matlab.ui.control.Label
        Button_LoadTable        matlab.ui.control.Button
    end

    
    properties (Access = private)
        tbl % Variabilă pentru a stoca tabelul încărcat 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_LoadTable
        function Button_LoadTable_Callback(app, event)
            [file, path] = uigetfile('*.csv', 'Selectați un fișier CSV');
          if isequal(file, 0)
        return; % Dacă utilizatorul anulează, nu face nimic
          end
    fullFilePath = fullfile(path, file);
    app.tbl = readtable(fullFilePath); % Încarcă datele în variabila tbl
    
    % Selectează doar coloanele cu tipuri acceptate pentru XVariable
     validColumns = app.tbl.Properties.VariableNames( ...
        varfun(@(x) isnumeric(x) || islogical(x) || isdatetime(x) || isduration(x), app.tbl, 'OutputFormat', 'uniform') ...
    );
    
    % Populează DropDown_XVar cu numele coloanelor tabelului
    app.DropDown_XVar.Items = app.tbl.Properties.VariableNames;
        end

        % Button pushed function: Button_Plot
        function Button_Plot_Callback(app, event)
            if isempty(app.tbl)
        uialert(app.UIFigure, 'Încărcați mai întâi un tabel!', 'Eroare');
        return;
           end

    xvar = app.DropDown_XVar.Value; % Variabila selectată pentru X
    
    % Setează opțiunile grafice (fără YLabel)
    options = {};
    if ~isempty(app.EditField_Title.Value)
        options = [options, 'Title', app.EditField_Title.Value];
    end
    if ~isempty(app.EditField_XLabel.Value)
        options = [options, 'XLabel', app.EditField_XLabel.Value];
    end
    if ~isempty(app.EditField_FontSize.Value)
        options = [options, 'FontSize', str2double(app.EditField_FontSize.Value)];
    end
    if ~isempty(app.EditField_Color.Value)
        options = [options, 'Color', app.EditField_Color.Value];
    end

    % Creează o nouă fereastră pentru stackedplot
    fig = figure('Name', 'Stacked Plot', 'NumberTitle', 'off');
    s = stackedplot(app.tbl, "XVariable", xvar, options{:});

    % Setăm eticheta axei Y separat
     if ~isempty(app.EditField_YLabel.Value)
        ylabel(s.Axes(1), app.EditField_YLabel.Value);
     end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button_LoadTable = uibutton(app.UIFigure, 'push');
            app.Button_LoadTable.ButtonPushedFcn = createCallbackFcn(app, @Button_LoadTable_Callback, true);
            app.Button_LoadTable.Tag = 'Button_LoadTable';
            app.Button_LoadTable.Text = 'Incarca tabel';
            app.VariableXDropDownLabel = uilabel(app.UIFigure);
            app.VariableXDropDownLabel.Text = 'Variable X';
            app.DropDown_XVar = uidropdown(app.UIFigure);
            app.DropDown_XVar.Tag = 'DropDown_XVar';
            app.TitleEditFieldLabel = uilabel(app.UIFigure);
            app.TitleEditFieldLabel.Text = 'Title';
            app.EditField_Title = uieditfield(app.UIFigure, 'text');
            app.EditField_Title.Tag = 'EditField_Title';
            app.XLabelEditFieldLabel = uilabel(app.UIFigure);
            app.XLabelEditFieldLabel.Text = 'XLabel';
            app.EditField_XLabel = uieditfield(app.UIFigure, 'text');
            app.EditField_XLabel.Tag = 'EditField_XLabel';
            app.YLabelEditFieldLabel = uilabel(app.UIFigure);
            app.YLabelEditFieldLabel.Text = 'YLabel';
            app.EditField_YLabel = uieditfield(app.UIFigure, 'text');
            app.EditField_YLabel.Tag = 'EditField_YLabel';
            app.FontSizeEditFieldLabel = uilabel(app.UIFigure);
            app.FontSizeEditFieldLabel.Text = 'FontSize';
            app.EditField_FontSize = uieditfield(app.UIFigure, 'text');
            app.EditField_FontSize.Tag = 'EditField_FontSize';
            app.ColorEditFieldLabel = uilabel(app.UIFigure);
            app.ColorEditFieldLabel.Text = 'Color';
            app.EditField_Color = uieditfield(app.UIFigure, 'text');
            app.EditField_Color.Tag = 'EditField_Color';
            app.Button_Plot = uibutton(app.UIFigure, 'push');
            app.Button_Plot.ButtonPushedFcn = createCallbackFcn(app, @Button_Plot_Callback, true);
            app.Button_Plot.Tag = 'Button_Plot';
            app.Button_Plot.Text = 'Generare Grafic';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end